classdef EqualizerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BrowseButton        matlab.ui.control.Button
        Label_3             matlab.ui.control.Label
        Slider_250          matlab.ui.control.Slider
        Label_4             matlab.ui.control.Label
        Slider_500          matlab.ui.control.Slider
        Label_5             matlab.ui.control.Label
        Slider_1000         matlab.ui.control.Slider
        Label_6             matlab.ui.control.Label
        Slider_2000         matlab.ui.control.Slider
        Label_7             matlab.ui.control.Label
        Slider_3000         matlab.ui.control.Slider
        Label_8             matlab.ui.control.Label
        Slider_4000         matlab.ui.control.Slider
        Label_9             matlab.ui.control.Label
        Slider_6000         matlab.ui.control.Slider
        fileEditFieldLabel  matlab.ui.control.Label
        fileEditField       matlab.ui.control.EditField
        SetZeroGainsButton  matlab.ui.control.Button
        Label_10            matlab.ui.control.Label
        Slider_8000         matlab.ui.control.Slider
        Label_11            matlab.ui.control.Label
        Slider_12000        matlab.ui.control.Slider
        Label_12            matlab.ui.control.Label
        Slider_20000        matlab.ui.control.Slider
        FrequencyLabel      matlab.ui.control.Label
        Label_13            matlab.ui.control.Label
        Slider_125          matlab.ui.control.Slider
        UIAxes              matlab.ui.control.UIAxes
        PlayButton          matlab.ui.control.Button
        StopButton          matlab.ui.control.Button
        Label_14            matlab.ui.control.Label
        Slider_0            matlab.ui.control.Slider
    end

    
    properties (Access = private)
        Property % Description
        data
        filterValues
        startValues
        x
        y
        n
        FFTLength
    end
    
    methods (Access = private)
        
        function filterValues = UpdateFilter(app)
            filterValues=[
                0,app.Slider_0.Value;
                125,app.Slider_125.Value;
                250,app.Slider_250.Value;
                500,app.Slider_500.Value;
                1000,app.Slider_1000.Value;
                2000,app.Slider_2000.Value;
                3000,app.Slider_3000.Value;
                4000,app.Slider_4000.Value;
                6000,app.Slider_6000.Value;
                8000,app.Slider_8000.Value;
                12000,app.Slider_12000.Value;
                20000,app.Slider_20000.Value];
            
            app.x = filterValues(:,1);          %X koordinaterna fï¿½r frekvenstabell
            
            app.y = filterValues(:,2);          %Vad vi multiplicerar med
            
            
        end
        
        function  YData = DrawGraph(app)
            YInterp = interp1(app.x, app.y, app.n, 'pchip')
            %update graph
            %app.UIAxes.Children(1).YData=app.y;
            app.UIAxes.Children(1).YData=YInterp;

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FFTLength=40000;                %Punkter i frekvensspektrum
            
            app.n = 1:1:app.FFTLength/2;            %Skapar vårt halva frekvensspektrum
            app.UpdateFilter()
            
            %plot(app.UIAxes,app.x,app.y ,'-*g')
            
            plot(app.UIAxes,app.n, zeros(1,20000) ,'-r')
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile('.\Ljudfiler\*.wav');
            app.fileEditField.Value = file;
            app.data=fullfile(path,file)
            
            
        end

        % Button pushed function: SetZeroGainsButton
        function Reset(app, event)
            app.Slider_0.Value = 0 ;
            app.Slider_125.Value = 0 ;
            app.Slider_250.Value = 0 ;
            app.Slider_500.Value = 0 ;
            app.Slider_1000.Value = 0 ;
            app.Slider_2000.Value = 0 ;
            app.Slider_3000.Value = 0 ;
            app.Slider_4000.Value = 0 ;
            app.Slider_6000.Value = 0 ;
            app.Slider_8000.Value = 0 ;
            app.Slider_12000.Value = 0 ;
            app.Slider_20000.Value = 0 ;
            app.UpdateFilter()
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            filter = interp1(app.x,2.^app.y*2,app.n, 'PCHIP');   %skapar ett filter utifrån värdena

            filter = [fliplr(filter), filter ]';        %Skapar speglat filter med gamla filtret
      
            [rawData, Fs]= audioread(app.data);      %laddar in ljud
      
            frec = stft(rawData,Fs,'Window',hann(app.FFTLength,'periodic'),'OverlapLength',app.FFTLength/2,'FFTLength',app.FFTLength);  %Skapar fönster i tidsdomän och gör fourieranalys i fönstret och sen flyttar fönstret framåt och upprepar
            
            frec2=frec.*filter;     %applicera filter
            
            output = istft(frec2, Fs,'Window',hann(app.FFTLength,'periodic'),'OverlapLength',app.FFTLength/2,'FFTLength',app.FFTLength, 'ConjugateSymmetric', true); %lika som tidigare fast tillbaka till tidsdomän
            sound(output, Fs);
            %Spelar upp behandlat ljud
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound
        end

        % Value changing function: Slider_0, Slider_1000, 
        % Slider_12000, Slider_125, Slider_2000, Slider_20000, 
        % Slider_250, Slider_4000, Slider_500, Slider_6000, 
        % Slider_8000
        function Slider_ValueChanging(app, event)
            app.UpdateFilter()
            app.DrawGraph()

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '250';
            app.Slider_250 = uislider(app.UIFigure);
            app.Slider_250.Limits = [-1 13];
            app.Slider_250.MajorTicks = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.Slider_250.MajorTickLabels = {'-6', '0', '6', '12', '18', '24', '30', '36', '42', '48', '54', '60', '66', '72', '78'};
            app.Slider_250.ValueChangingFcn = createCallbackFcn(app, @Slider_ValueChanging, true);
            app.Slider_250.MinorTicks = [];
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '500';
            app.Slider_500 = uislider(app.UIFigure);
            app.Slider_500.Limits = [-1 13];
            app.Slider_500.MajorTicks = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.Slider_500.MajorTickLabels = {'-6', '0', '6', '12', '18', '24', '30', '36', '42', '48', '54', '60', '66', '72', '78'};
            app.Slider_500.ValueChangingFcn = createCallbackFcn(app, @Slider_ValueChanging, true);
            app.Slider_500.MinorTicks = [];
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '1000';
            app.Slider_1000 = uislider(app.UIFigure);
            app.Slider_1000.Limits = [-1 13];
            app.Slider_1000.MajorTicks = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.Slider_1000.MajorTickLabels = {'-6', '0', '6', '12', '18', '24', '30', '36', '42', '48', '54', '60', '66', '72', '78'};
            app.Slider_1000.ValueChangingFcn = createCallbackFcn(app, @Slider_ValueChanging, true);
            app.Slider_1000.MinorTicks = [];
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '2000';
            app.Slider_2000 = uislider(app.UIFigure);
            app.Slider_2000.Limits = [-1 13];
            app.Slider_2000.MajorTicks = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.Slider_2000.MajorTickLabels = {'-6', '0', '6', '12', '18', '24', '30', '36', '42', '48', '54', '60', '66', '72', '78'};
            app.Slider_2000.ValueChangingFcn = createCallbackFcn(app, @Slider_ValueChanging, true);
            app.Slider_2000.MinorTicks = [];
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '3000';
            app.Slider_3000 = uislider(app.UIFigure);
            app.Slider_3000.Limits = [-1 13];
            app.Slider_3000.MajorTicks = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.Slider_3000.MajorTickLabels = {'-6', '0', '6', '12', '18', '24', '30', '36', '42', '48', '54', '60', '66', '72', '78'};
            app.Slider_3000.MinorTicks = [];
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '4000';
            app.Slider_4000 = uislider(app.UIFigure);
            app.Slider_4000.Limits = [-1 13];
            app.Slider_4000.MajorTicks = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.Slider_4000.MajorTickLabels = {'-6', '0', '6', '12', '18', '24', '30', '36', '42', '48', '54', '60', '66', '72', '78'};
            app.Slider_4000.ValueChangingFcn = createCallbackFcn(app, @Slider_ValueChanging, true);
            app.Slider_4000.MinorTicks = [];
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '6000';
            app.Slider_6000 = uislider(app.UIFigure);
            app.Slider_6000.Limits = [-1 13];
            app.Slider_6000.MajorTicks = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.Slider_6000.MajorTickLabels = {'-6', '0', '6', '12', '18', '24', '30', '36', '42', '48', '54', '60', '66', '72', '78'};
            app.Slider_6000.ValueChangingFcn = createCallbackFcn(app, @Slider_ValueChanging, true);
            app.Slider_6000.MinorTicks = [];
            app.fileEditFieldLabel = uilabel(app.UIFigure);
            app.fileEditFieldLabel.Text = 'file:';
            app.fileEditField = uieditfield(app.UIFigure, 'text');
            app.SetZeroGainsButton = uibutton(app.UIFigure, 'push');
            app.SetZeroGainsButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.SetZeroGainsButton.Text = 'Set Zero Gains';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '8000';
            app.Slider_8000 = uislider(app.UIFigure);
            app.Slider_8000.Limits = [-1 13];
            app.Slider_8000.MajorTicks = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.Slider_8000.MajorTickLabels = {'-6', '0', '6', '12', '18', '24', '30', '36', '42', '48', '54', '60', '66', '72', '78'};
            app.Slider_8000.ValueChangingFcn = createCallbackFcn(app, @Slider_ValueChanging, true);
            app.Slider_8000.MinorTicks = [];
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '12000';
            app.Slider_12000 = uislider(app.UIFigure);
            app.Slider_12000.Limits = [-1 13];
            app.Slider_12000.MajorTicks = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.Slider_12000.MajorTickLabels = {'-6', '0', '6', '12', '18', '24', '30', '36', '42', '48', '54', '60', '66', '72', '78'};
            app.Slider_12000.ValueChangingFcn = createCallbackFcn(app, @Slider_ValueChanging, true);
            app.Slider_12000.MinorTicks = [];
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '20000';
            app.Slider_20000 = uislider(app.UIFigure);
            app.Slider_20000.Limits = [-1 13];
            app.Slider_20000.MajorTicks = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.Slider_20000.MajorTickLabels = {'-6', '0', '6', '12', '18', '24', '30', '36', '42', '48', '54', '60', '66', '72', '78'};
            app.Slider_20000.ValueChangingFcn = createCallbackFcn(app, @Slider_ValueChanging, true);
            app.Slider_20000.MinorTicks = [];
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency:';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '125';
            app.Slider_125 = uislider(app.UIFigure);
            app.Slider_125.Limits = [-1 13];
            app.Slider_125.MajorTicks = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.Slider_125.MajorTickLabels = {'-6', '0', '6', '12', '18', '24', '30', '36', '42', '48', '54', '60', '66', '72', '78'};
            app.Slider_125.ValueChangingFcn = createCallbackFcn(app, @Slider_ValueChanging, true);
            app.Slider_125.MinorTicks = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Frequency (kHz)')
            ylabel(app.UIAxes, 'Gain (dB)')
            app.UIAxes.XLim = [20 20000];
            app.UIAxes.YLim = [-1 13];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [0 125 250 500 1000 2000 3000 4000 6000 8000 12000 20000];
            app.UIAxes.XTickLabel = {'0'; '125'; '250'; '500'; '1000'; '2000'; '3000'; '4000'; '6000'; '8000'; '12000'; '20000'};
            app.UIAxes.YTick = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.UIAxes.YTickLabel = {'-6'; '0'; '6'; '12'; '18'; '24'; '30'; '36'; '42'; '48'; '54'; '60'; '66'; '72'; '78'; '0'; '12'; '13'};
            app.UIAxes.XScale = 'log';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '0';
            app.Slider_0 = uislider(app.UIFigure);
            app.Slider_0.Limits = [-1 13];
            app.Slider_0.MajorTicks = [-1 0 1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.Slider_0.MajorTickLabels = {'-6', '0', '6', '12', '18', '24', '30', '36', '42', '48', '54', '60', '66', '72', '78'};
            app.Slider_0.ValueChangingFcn = createCallbackFcn(app, @Slider_ValueChanging, true);
            app.Slider_0.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EqualizerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end